classdef MedicalImagingProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        GenerateImagesButton           matlab.ui.control.Button
        ImageSizeEditFieldLabel        matlab.ui.control.Label
        ImageSizeEditField             matlab.ui.control.NumericEditField
        OutsideObjectLeftBoundaryEditFieldLabel  matlab.ui.control.Label
        OutsideObjectLeftBoundaryEditField  matlab.ui.control.NumericEditField
        OutsideObjectRightBoundaryEditFieldLabel  matlab.ui.control.Label
        OutsideObjectRightBoundaryEditField  matlab.ui.control.NumericEditField
        InsideObjectLeftBoundaryEditFieldLabel  matlab.ui.control.Label
        InsideObjectLeftBoundaryEditField  matlab.ui.control.NumericEditField
        InsideObjectRightBoundaryEditFieldLabel  matlab.ui.control.Label
        InsideObjectRightBoundaryEditField  matlab.ui.control.NumericEditField
        OutsideObjectDensityEditFieldLabel  matlab.ui.control.Label
        OutsideObjectDensityEditField  matlab.ui.control.NumericEditField
        InsideObjectDensityEditFieldLabel  matlab.ui.control.Label
        InsideObjectDensityEditField   matlab.ui.control.NumericEditField
        OutsideObjectT1ValueEditFieldLabel  matlab.ui.control.Label
        OutsideObjectT1ValueEditField  matlab.ui.control.NumericEditField
        OutsideObjectT2ValueEditFieldLabel  matlab.ui.control.Label
        OutsideObjectT2ValueEditField  matlab.ui.control.NumericEditField
        InsideObjectT1ValueEditFieldLabel  matlab.ui.control.Label
        InsideObjectT1ValueEditField   matlab.ui.control.NumericEditField
        InsideObjectT2ValueEditFieldLabel  matlab.ui.control.Label
        InsideObjectT2ValueEditField   matlab.ui.control.NumericEditField
        Lamp                           matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_8                         matlab.ui.control.Lamp
        Lamp_9                         matlab.ui.control.Lamp
        Lamp_10                        matlab.ui.control.Lamp
        CheckInputsButton              matlab.ui.control.Button
        TRValueEditFieldLabel          matlab.ui.control.Label
        TRValueEditField               matlab.ui.control.NumericEditField
        TEValueEditFieldLabel          matlab.ui.control.Label
        TEValueEditField               matlab.ui.control.NumericEditField
        Lamp_11                        matlab.ui.control.Lamp
        Lamp_12                        matlab.ui.control.Lamp
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        Lamp_13                        matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckInputsButton
        function CheckInputsButtonPushed(app, event)
            
            % Callback for the 'Check Inputs' button
            
            % Checks the 'Image Size' input
            if (app.ImageSizeEditField.Value > 0)
                
                app.Lamp_13.Color = 'green';
                
            else
                
                app.Lamp_13.Color = 'red';
                
            end
            
            % Checks the 'TR Value' input
            if (app.TRValueEditField.Value >= 0)
                
                app.Lamp_11.Color = 'green';
                
            else
                
                app.Lamp_11.Color = 'red';
                
            end
            
            % Checks the 'TE Value' input
            if (app.TEValueEditField.Value >= 0)
                
                app.Lamp_12.Color = 'green';
                
            else
                
                app.Lamp_12.Color = 'red';
                
            end
            
            % Checks the 'Outside Object Left Boundary' input
            if (app.OutsideObjectLeftBoundaryEditField.Value > 0 && app.OutsideObjectLeftBoundaryEditField.Value < app.InsideObjectLeftBoundaryEditField.Value)
            
                app.Lamp.Color = 'green';
                
            else
                
                app.Lamp.Color = 'red';
                  
            end
            
            % Checks the 'Outside Object Right Boundary' input
            if (app.OutsideObjectRightBoundaryEditField.Value > 0 && app.OutsideObjectRightBoundaryEditField.Value > app.InsideObjectRightBoundaryEditField.Value)
                
                app.Lamp_2.Color = 'green';
                
            else
                
                app.Lamp_2.Color = 'red';

            end
            
            % Checks the 'Outside Object T1 Value' input
            if (app.OutsideObjectT1ValueEditField.Value > 0)
                
                app.Lamp_3.Color = 'green';
                
            else
                
                app.Lamp_3.Color = 'red';

            end
           
            % Checks the 'Outside Object T2 Value' input
            if (app.OutsideObjectT2ValueEditField.Value > 0)
                
                app.Lamp_4.Color = 'green';
                
            else
                
                app.Lamp_4.Color = 'red';

            end
            
            % Checks the 'Outside Object Density' input
            if (app.OutsideObjectDensityEditField.Value >= 0)
                
                app.Lamp_5.Color = 'green';
                
            else
                
                app.Lamp_5.Color = 'red';

            end

            % Checks the 'Inside Object Left Boundary' input
            if (app.InsideObjectLeftBoundaryEditField.Value > 0 && app.InsideObjectLeftBoundaryEditField.Value > app.OutsideObjectLeftBoundaryEditField.Value)
            
                app.Lamp_6.Color = 'green';
                                
            else
                
                app.Lamp_6.Color = 'red';

            end
            
            % Checks the 'Inside Object Right Boundary' input
            if (app.InsideObjectRightBoundaryEditField.Value > 0 && app.InsideObjectRightBoundaryEditField.Value < app.OutsideObjectRightBoundaryEditField.Value)
            
                app.Lamp_7.Color = 'green';
                                
            else
                
                app.Lamp_7.Color = 'red';

            end
            
            % Checks the 'Inside Object T1 Value' input
            if (app.InsideObjectT1ValueEditField.Value > 0)
                
                app.Lamp_10.Color = 'green';
                
            else
                
                app.Lamp_10.Color = 'red';

            end
           
            % Checks the 'Inside Object T2 Value' input
            if (app.InsideObjectT2ValueEditField.Value > 0)
                
                app.Lamp_9.Color = 'green';
                
            else
                
                app.Lamp_9.Color = 'red';

            end
            
            % Checks the 'Inside Object Density' input
            if (app.InsideObjectDensityEditField.Value >= 0)
                
                app.Lamp_8.Color = 'green';
                
            else
                
                app.Lamp_8.Color = 'red';

            end
                       
        end

        % Button pushed function: GenerateImagesButton
        function GenerateImagesButtonPushed(app, event)
            
            % Callback for the 'Generate Images' button

            % Preallocated for speed improvements
            MatrixPlot = zeros(app.ImageSizeEditField.Value);
            T1Plot = zeros(app.ImageSizeEditField.Value);
            T2Plot = zeros(app.ImageSizeEditField.Value);
            DensityPlot = zeros(app.ImageSizeEditField.Value);
            ImagePlot = zeros(app.ImageSizeEditField.Value);
            
            % Generates the various plots
            for r = 1:app.ImageSizeEditField.Value
                
                % Space from edge of matrix to outside object's
                % left boundary
                for c = 1:app.OutsideObjectLeftBoundaryEditField.Value
                    
                    T1Plot(r, c) = 1;
                    T2Plot(r, c) = 1;
  
                end
                
                % Space from outside object's left boundary to inside
                % object's left boundary
                for c = app.OutsideObjectLeftBoundaryEditField.Value + 1:app.InsideObjectLeftBoundaryEditField.Value
                    
                    MatrixPlot(r, c) = 1;
                    T1Plot(r, c) = app.OutsideObjectT1ValueEditField.Value;
                    T2Plot(r, c) = app.OutsideObjectT2ValueEditField.Value;
                    DensityPlot(r, c) = app.OutsideObjectDensityEditField.Value;
                    
                end
                
                % Space from inside object's left boundary to inside
                % object's right boundary
                for c = app.InsideObjectLeftBoundaryEditField.Value + 1:app.InsideObjectRightBoundaryEditField.Value
                    
                    MatrixPlot(r, c) = 1;
                    T1Plot(r, c) = app.InsideObjectT1ValueEditField.Value;
                    T2Plot(r, c) = app.InsideObjectT2ValueEditField.Value;
                    DensityPlot(r, c) = app.InsideObjectDensityEditField.Value;
                    
                end
                
                % Space from inside object's right boundary to outside
                % object's right boundary
                for c = app.InsideObjectRightBoundaryEditField.Value + 1:app.OutsideObjectRightBoundaryEditField.Value
                    
                    MatrixPlot(r, c) = 1;
                    T1Plot(r, c) = app.OutsideObjectT1ValueEditField.Value;
                    T2Plot(r, c) = app.OutsideObjectT2ValueEditField.Value;
                    DensityPlot(r, c) = app.OutsideObjectDensityEditField.Value;
                    
                end
                
                % Space from outside object's right boundary to edge of the
                % matrix
                for c = app.OutsideObjectRightBoundaryEditField.Value + 1:app.ImageSizeEditField.Value
                    
                    T1Plot(r, c) = 1;
                    T2Plot(r, c) = 1;
                    
                end
                
                % Generates the output image
                for c = 1:app.ImageSizeEditField.Value
                
                    ImagePlot(r, c) = DensityPlot(r, c) * (2 - exp(-app.TRValueEditField.Value / T1Plot(r, c))) * exp(-app.TEValueEditField.Value / T2Plot(r, c));
                    
                end

            end
            
            % Output the 'T1 Plot'
            figure('Name', 'T1 Plot')
            imagesc(T1Plot);
            title('T1 Plot')
            colormap(gray);
            
            % Output the 'T2 Plot'
            figure('Name', 'T2 Plot')
            imagesc(T2Plot);
            title('T2 Plot')
            colormap(gray);
            
            % Output the 'Density Plot'
            figure('Name', 'Density Plot')
            imagesc(DensityPlot);
            title('Density Plot')
            colormap(gray);
            
            % Output the 'Output Image'
            figure('Name', 'Output Image')
            imagesc(ImagePlot)
            title('Output Image')
            colormap(gray);
            
            % 'colormap' is used because the default blue/yellow color
            % scheme is ugly and not helpful
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Virtual MRI Contrast Simulator'};
            app.GenerateImagesButton = uibutton(app.UIFigure, 'push');
            app.GenerateImagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateImagesButtonPushed, true);
            app.GenerateImagesButton.Text = 'Generate Images';
            app.ImageSizeEditFieldLabel = uilabel(app.UIFigure);
            app.ImageSizeEditFieldLabel.Text = 'Image Size';
            app.ImageSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutsideObjectLeftBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.OutsideObjectLeftBoundaryEditFieldLabel.Text = 'Outside Object Left Boundary';
            app.OutsideObjectLeftBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutsideObjectRightBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.OutsideObjectRightBoundaryEditFieldLabel.Text = 'Outside Object Right Boundary';
            app.OutsideObjectRightBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsideObjectLeftBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.InsideObjectLeftBoundaryEditFieldLabel.Text = 'Inside Object Left Boundary';
            app.InsideObjectLeftBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsideObjectRightBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.InsideObjectRightBoundaryEditFieldLabel.Text = 'Inside Object Right Boundary';
            app.InsideObjectRightBoundaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutsideObjectDensityEditFieldLabel = uilabel(app.UIFigure);
            app.OutsideObjectDensityEditFieldLabel.Text = 'Outside Object Density';
            app.OutsideObjectDensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsideObjectDensityEditFieldLabel = uilabel(app.UIFigure);
            app.InsideObjectDensityEditFieldLabel.Text = 'Inside Object Density';
            app.InsideObjectDensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutsideObjectT1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.OutsideObjectT1ValueEditFieldLabel.Text = 'Outside Object T1 Value';
            app.OutsideObjectT1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutsideObjectT2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.OutsideObjectT2ValueEditFieldLabel.Text = 'Outside Object T2 Value';
            app.OutsideObjectT2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsideObjectT1ValueEditFieldLabel = uilabel(app.UIFigure);
            app.InsideObjectT1ValueEditFieldLabel.Text = 'Inside Object T1 Value';
            app.InsideObjectT1ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsideObjectT2ValueEditFieldLabel = uilabel(app.UIFigure);
            app.InsideObjectT2ValueEditFieldLabel.Text = 'Inside Object T2 Value';
            app.InsideObjectT2ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.CheckInputsButton = uibutton(app.UIFigure, 'push');
            app.CheckInputsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckInputsButtonPushed, true);
            app.CheckInputsButton.Text = 'Check Inputs';
            app.TRValueEditFieldLabel = uilabel(app.UIFigure);
            app.TRValueEditFieldLabel.Text = 'TR Value';
            app.TRValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEValueEditFieldLabel = uilabel(app.UIFigure);
            app.TEValueEditFieldLabel.Text = 'TE Value';
            app.TEValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.Lamp_11 = uilamp(app.UIFigure);
            app.Lamp_12 = uilamp(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Step 1: Enter all values and click the below button.'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Step 2: Click the below button once all the lamps are green.'};
            app.Lamp_13 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MedicalImagingProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end